classdef Random_number_generator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel  matlab.ui.container.Panel
        ChoisirladistributionDropDownLabel  matlab.ui.control.Label
        ChoisirladistributionDropDown  matlab.ui.control.DropDown
        aEditFieldLabel                matlab.ui.control.Label
        aEditField                     matlab.ui.control.NumericEditField
        bEditFieldLabel                matlab.ui.control.Label
        bEditField                     matlab.ui.control.NumericEditField
        LancerlasimulationButton       matlab.ui.control.Button
        UITable                        matlab.ui.control.Table
        Label                          matlab.ui.control.Label
        EditField                      matlab.ui.control.NumericEditField
        mEditFieldLabel                matlab.ui.control.Label
        mEditField                     matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        EditField_2                    matlab.ui.control.NumericEditField
        Label_3                        matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        kEditFieldLabel                matlab.ui.control.Label
        kEditField                     matlab.ui.control.NumericEditField
        TailledelchantillongnrerEditFieldLabel  matlab.ui.control.Label
        TailledelchantillongnrerEditField  matlab.ui.control.NumericEditField
        LesnombresalatoiresgnrsLabel   matlab.ui.control.Label
        TPFilesdattenteetSimulationKEBIRSaraSIQ4Label  matlab.ui.control.Label
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.aEditField.Visible='off';
            app.aEditFieldLabel.Visible='off';
            app.bEditField.Visible='off';
            app.bEditFieldLabel.Visible='off';
            app.EditField.Visible='off';
            app.Label.Visible='off';
            app.mEditField.Visible='off';
            app.mEditFieldLabel.Visible='off';
            app.EditField_2.Visible='off';
            app.Label_2.Visible='off';
            app.EditField_3.Visible='off';
            app.Label_3.Visible='off';
            app.kEditField.Visible='off';
            app.kEditFieldLabel.Visible='off';
            if (string(app.ChoisirladistributionDropDown.Value)=='Loi Uniforme')
            app.aEditField.Visible='on';
            app.bEditField.Visible='on';
            app.aEditFieldLabel.Visible='on';
            app.bEditFieldLabel.Visible='on';
            end 
        end

        % Callback function
        function AfficherlesnombresalatoiresgnrsButtonPushed(app, event)
             app.AfficherlesnombresalatoiresgnrsButton.Visible='off'
              app.NepasafficherlesnombresalatoiresgnrsButton.Visible='on'
            app.UITable.Visible='on';
             app.LesnombresalatoiresgnrsLabel.Visible='on';
        end

        % Value changed function: ChoisirladistributionDropDown
        function ChoisirladistributionDropDownValueChanged(app, event)
            startupFcn(app)
            value = app.ChoisirladistributionDropDown.Value;
            set(app.UITable, 'Data', cell(size(get(app.UITable,'Data'))));
            app.UITable.Data={};
            if(string(value)=='Loi Uniforme')
            app.aEditField.Visible='on';
            app.bEditField.Visible='on';
            app.aEditFieldLabel.Visible='on';
            app.bEditFieldLabel.Visible='on';
            end 
            if(string(value)=='Loi Exponentielle')
            app.EditField.Visible='on';  
            app.Label.Visible='on';
            end
            if(string(value)=='Loi de Poisson')
            app.EditField.Visible='on';  
            app.Label.Visible='on';
            end
            if(string(value)=='Loi Normale')
            app.mEditField.Visible='on';
            app.mEditFieldLabel.Visible='on';
            app.EditField_2.Visible='on';
            app.Label_2.Visible='on';
            end
            if(string(value)=='Loi D''Erlang')
            app.EditField_3.Visible='on';
            app.Label_3.Visible='on';
            app.kEditField.Visible='on';
            app.kEditFieldLabel.Visible='on';
            end
            
        end

        % Button pushed function: LancerlasimulationButton
        function LancerlasimulationButtonPushed(app, event)
            set(app.UITable, 'Data', cell(size(get(app.UITable,'Data'))));
            app.UITable.Data={};
            value = app.ChoisirladistributionDropDown.Value;
                Y=[]
                B=[]
             if(string(value)=='Loi Uniforme')
                b_a = app.bEditField.Value - app.aEditField.Value;
                a=app.aEditField.Value;
                for  i=1:app.TailledelchantillongnrerEditField.Value
                X=rand;
                B= [B i]
                Y=[Y, b_a*X + a];
                app.UITable.Data = [app.UITable.Data;{ i, Y(i)}]
                 hist(Y)
                pause(0.000000000000000001)
                end
                pause(2)
                  histfit(Y,[],'kernel');
                  ytix=get(gca, 'YTick')
                  set(gca,'YTick',ytix,'YTickLabel',ytix/app.TailledelchantillongnrerEditField.Value)
                   test_cdf=makedist('Uniform','Lower',app.aEditField.Value,'Upper',app.bEditField.Value)
                    
                    [h,p] = kstest(Y,'CDF',test_cdf,'Alpha',0.01)
             end
             
             if(string(value)=='Loi Exponentielle')
                for  i=1:app.TailledelchantillongnrerEditField.Value
                X=rand;
                B= [B i]
                Y=[Y, (-1/app.EditField.Value)*log(X)];
                app.UITable.Data = [app.UITable.Data;{ i, Y(i)}]
                 hist(Y)
                pause(0.000000000000000001)
                end
                pause(2)
                  histfit(Y,[],'exponential');
                  ytix=get(gca, 'YTick')
                  set(gca,'YTick',ytix,'YTickLabel',ytix/app.TailledelchantillongnrerEditField.Value)
                  
                 
                  test_cdf= makedist('Exponential','mu',app.EditField.Value)
                    
                    [h,p] = kstest(Y,'CDF',test_cdf,'Alpha',0.01)
             end
             
             if(string(value)=='Loi de Poisson')
              j=1;
              i=1;
                  while  (j<app.TailledelchantillongnrerEditField.Value)
                  b=1;
                  k=exp(-app.EditField.Value);
                  P=1;
                  X=rand;
                  P=P*X;
                  i=i+1;
                      while (P>= k)
                      X=rand;
                      P=P*X;
                      b=b+1;
                      i=i+1;
                      end 
                 B=[B j]
                 Y=[Y, b-1];
                
                 hist(Y)
                 app.UITable.Data=[app.UITable.Data;{ j,Y(j)}]
               
                 pause(0.00000000001)
                 j=j+1;
                 
                  end
                  pause(2)
                  histfit(Y,[],'poisson');
                  ytix=get(gca, 'YTick')
                  set(gca,'YTick',ytix,'YTickLabel',ytix/app.TailledelchantillongnrerEditField.Value)


                    %cdfplot(Y)
                    
                   % test_cdf=makedist('Poisson','lambda',app.EditField.Value);
                   % x_values = linspace(min(Y),max(Y))
                    %plot(x_values, cdf(test_cdf,Y),'r-')
                    pause(2)
           
                   test_cdf=makedist('Poisson','lambda',app.EditField.Value);
                    
                    [h,p] = kstest(Y,'CDF',test_cdf,'Alpha',0.01)
                  
             end
             
             if(string(value)=='Loi Normale')
                for i=1:app.TailledelchantillongnrerEditField.Value
                    Z=(sqrt(-2 * log( rand))*cos(2* (pi) * rand));
                    Y=[Y, app.EditField_2.Value * Z + app.mEditField.Value ]
                    app.UITable.Data = [app.UITable.Data;{i, Y(i)}]
                    B= [B i]
                  
                    pause(0.000000000000000001)
                    hist(Y)
                    
                    
                end
                  pause(2)
                  histfit(Y,[],'normal');
                  ytix=get(gca, 'YTick')
                  set(gca,'YTick',ytix,'YTickLabel',ytix/app.TailledelchantillongnrerEditField.Value)
                  pause(2)
                  X=(Y-app.mEditField.Value )/app.EditField_2.Value 
                    cdfplot(X)
                    hold on
                    x_values = linspace(min(X),max(X))
                    plot(x_values, normcdf(x_values,0,1),'r-')
                    pause(2)
           
                   test_cdf=makedist('Normal','mu',app.mEditField.Value,'sigma',app.EditField_2.Value)
                    
                    [h,p] = kstest(Y,'CDF',test_cdf,'Alpha',0.01)
               
             end
             
             
            if(string(value)=='Loi D''Erlang')
                j=1;
                for  i=1:app.TailledelchantillongnrerEditField.Value
                    P=1;
                    k=1;
                    while  k< app.kEditField.Value 
                        P= P * rand;
                        k=k+1;
                        j=j+1;
                    end 
                B= [B i]
                Y=[Y, (-1/app.EditField_3.Value)*log(P)];
                app.UITable.Data = [app.UITable.Data;{ i, Y(i)}]
                hist(Y)
                pause(0.000000000000000001);
                end
                pause(2)
                  histfit(Y,[],'gamma');
                  ytix=get(gca, 'YTick')
                  set(gca,'YTick',ytix,'YTickLabel',ytix/app.TailledelchantillongnrerEditField.Value)
                   test_cdf=makedist('Gamma','a',app.EditField_3.Value,'b',app.kEditField.Value)
                    
                    [h,p] = kstest(Y,'CDF',test_cdf,'Alpha',0.01)
            end
                 
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel = uipanel(app.UIFigure);
            app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel.Title = 'TP Files d''attente et Simulation -KEBIR Sara SIQ 4-';
            app.ChoisirladistributionDropDownLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.ChoisirladistributionDropDownLabel.Text = 'Choisir la distribution';
            app.ChoisirladistributionDropDown = uidropdown(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.ChoisirladistributionDropDown.Items = {'Loi Uniforme', 'Loi Exponentielle', 'Loi de Poisson', 'Loi Normale', 'Loi D''Erlang'};
            app.ChoisirladistributionDropDown.ValueChangedFcn = createCallbackFcn(app, @ChoisirladistributionDropDownValueChanged, true);
            app.ChoisirladistributionDropDown.Value = 'Loi Uniforme';
            app.aEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.bEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.bEditFieldLabel.Text = 'b';
            app.bEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.LancerlasimulationButton = uibutton(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'push');
            app.LancerlasimulationButton.ButtonPushedFcn = createCallbackFcn(app, @LancerlasimulationButtonPushed, true);
            app.LancerlasimulationButton.Text = 'Lancer la simulation';
            app.UITable = uitable(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.UITable.ColumnName = {'Indice'; 'Nb généré'};
            app.UITable.RowName = {};
            app.Label = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.Label.Text = '𝛌';
            app.EditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.mEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.Label_2 = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.Label_2.Text = ' σ';
            app.EditField_2 = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.Label_3 = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.Label_3.Text = '𝛌';
            app.EditField_3 = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.kEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.kEditFieldLabel.Text = 'k';
            app.kEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.TailledelchantillongnrerEditFieldLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.TailledelchantillongnrerEditFieldLabel.Text = 'Taille de l''échantillon à générer';
            app.TailledelchantillongnrerEditField = uieditfield(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel, 'numeric');
            app.LesnombresalatoiresgnrsLabel = uilabel(app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Panel);
            app.LesnombresalatoiresgnrsLabel.Text = 'Les nombres aléatoires générés :';
            app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Label = uilabel(app.UIFigure);
            app.TPFilesdattenteetSimulationKEBIRSaraSIQ4Label.Text = 'TP Files d''attente et Simulation -KEBIR Sara SIQ 4-';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Random_number_generator

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end